classdef ComSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        Label                  matlab.ui.control.Label
        DropdownComCode        matlab.ui.control.DropDown
        Label_2                matlab.ui.control.Label
        DropdownComBaud        matlab.ui.control.DropDown
        Label_3                matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        EditField_2            matlab.ui.control.EditField
        Label_5                matlab.ui.control.Label
        EditField_3            matlab.ui.control.NumericEditField
        BytesEditFieldLabel_2  matlab.ui.control.Label
        EditBufferSize         matlab.ui.control.NumericEditField
        ButtonOK               matlab.ui.control.Button
        ButtonCancel           matlab.ui.control.Button
        ButtonTest             matlab.ui.control.Button
        Panel_2                matlab.ui.container.Panel
        TextComData            matlab.ui.control.TextArea
        BytesEditFieldLabel    matlab.ui.control.Label
        EditBytes              matlab.ui.control.NumericEditField
        CheckBoxHEX            matlab.ui.control.CheckBox
        CheckBoxSend           matlab.ui.control.CheckBox
    end

    properties (Access = public)
        callingApp;
        
        SCom;
        ComCode; % 串口号
        ComBaud; % 波特率
        BufferSize;
    end
    
    methods (Access = private)
        
        function getComParameter(app)
            app.ComCode = app.DropdownComCode.Value;
            app.ComBaud = str2double(app.DropdownComBaud.Value);
            app.BufferSize = app.EditBufferSize.Value;
            
        end
        function openCom(app)
            app.SCom = serial(app.ComCode);
            set(app.SCom, 'BaudRate', app.ComBaud ,...
                'Parity', 'NONE', 'DataBits', 8, 'StopBits', 1);
            %% 尝试打开串口
            try
                if(strcmp(app.SCom.Status,'open'))
                    flose(app.SCom)
                end
                fopen(app.SCom);  %打开串口
            catch   % 若串口打开失败，提示“串口打开失败！”
                msgbox('串口打开失败！');
                return;
            end
            
        end
        
        function closeCom(app)
             %% 尝试关闭串口
            try
                if(strcmp(app.SCom.Status,'open'))
                    flose(app.SCom)
                    delete(app.SCom)
                    clear app.SCom
                end
            catch   % 若串口关闭失败，提示“串口关闭失败！”
                msgbox('串口关闭失败！');
                return;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            movegui(app.UIFigure,'center');
            app.callingApp = mainApp;
        end

        % Button pushed function: ButtonCancel
        function ButtonCancelPushed(app, event)
            closeCom(app);
            delete(app);
        end

        % Button pushed function: ButtonOK
        function ButtonOKPushed(app, event)
            getComParameter(app);
            setCom(app.callingApp,app.ComCode,app.ComBaud,app.BufferSize);

            closeCom(app);
            delete(app);
        end

        % Value changed function: TextComData
        function TextComDataValueChanged(app, event)
            value = app.TextComData.Value;
            NEWSTR = replace(value,' ','');     % 去空格。
            app.EditBytes.Value = strlength(NEWSTR);
        end

        % Button pushed function: ButtonTest
        function ButtonTestPushed(app, event)
            getComParameter(app);
            openCom(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '串口参数设置';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '串口参数';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '串口号';
            app.DropdownComCode = uidropdown(app.Panel);
            app.DropdownComCode.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.DropdownComCode.Value = 'COM3';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '波特率';
            app.DropdownComBaud = uidropdown(app.Panel);
            app.DropdownComBaud.Items = {'115200'};
            app.DropdownComBaud.Editable = 'on';
            app.DropdownComBaud.Value = '115200';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '数据位';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 1024];
            app.EditField.Editable = 'off';
            app.EditField.Value = 8;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '校验位';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = '无';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '停止位';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Limits = [0 1024];
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 1;
            app.BytesEditFieldLabel_2 = uilabel(app.Panel);
            app.BytesEditFieldLabel_2.Text = '缓冲区(Bytes)';
            app.EditBufferSize = uieditfield(app.Panel, 'numeric');
            app.EditBufferSize.Limits = [0 1024];
            app.EditBufferSize.Tooltip = {'6个通道的浮点数据，每个点24字节。'};
            app.EditBufferSize.Value = 480;
            app.ButtonOK = uibutton(app.UIFigure, 'push');
            app.ButtonOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonOKPushed, true);
            app.ButtonOK.Text = '确定';
            app.ButtonCancel = uibutton(app.UIFigure, 'push');
            app.ButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelPushed, true);
            app.ButtonCancel.Text = '取消';
            app.ButtonTest = uibutton(app.UIFigure, 'push');
            app.ButtonTest.ButtonPushedFcn = createCallbackFcn(app, @ButtonTestPushed, true);
            app.ButtonTest.Text = '测试';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '串口数据';
            app.TextComData = uitextarea(app.Panel_2);
            app.TextComData.ValueChangedFcn = createCallbackFcn(app, @TextComDataValueChanged, true);
            app.BytesEditFieldLabel = uilabel(app.Panel_2);
            app.BytesEditFieldLabel.Text = '字节数(Bytes)';
            app.EditBytes = uieditfield(app.Panel_2, 'numeric');
            app.CheckBoxHEX = uicheckbox(app.Panel_2);
            app.CheckBoxHEX.Text = 'HEX 显示';
            app.CheckBoxHEX.Value = true;
            app.CheckBoxSend = uicheckbox(app.Panel_2);
            app.CheckBoxSend.Text = '发送';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ComSettings(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end