classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MenuFile                       matlab.ui.container.Menu
        MenuExit                       matlab.ui.container.Menu
        MenuSettings                   matlab.ui.container.Menu
        MenuComSettings                matlab.ui.container.Menu
        MenuHelp                       matlab.ui.container.Menu
        MenuVersion                    matlab.ui.container.Menu
        UIAxes                         matlab.ui.control.UIAxes
        Panel_3                        matlab.ui.container.Panel
        ButtonBPSettings               matlab.ui.control.Button
        ButtonBPTrain                  matlab.ui.control.Button
        ButtonBPCoefSave               matlab.ui.control.Button
        ButtonCharacteristicValue      matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        ButtonMultiTargetRecognize     matlab.ui.control.Button
        ButtonSingleTargetRecognize    matlab.ui.control.Button
        ButtonRealTimeTargetRecognize  matlab.ui.control.Button
        ButtonCoefLoad                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Panel_2                        matlab.ui.container.Panel
        ButtonStartScan                matlab.ui.control.Button
        ButtonContour                  matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        YEditFieldLabel                matlab.ui.control.Label
        EditDeltaY                     matlab.ui.control.NumericEditField
        Label_7                        matlab.ui.control.Label
        EditFieldLabel                 matlab.ui.control.Label
        EditScanRadius                 matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        EditScanVelocity               matlab.ui.control.NumericEditField
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        EditCurrentAngle               matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        EditFs                         matlab.ui.control.NumericEditField
        Label_9                        matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        EditAccumulateDeltaY           matlab.ui.control.NumericEditField
        Label_10                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        % ----------- 串口部分 ----------- %
        ComCode               % 串口号
        ComBaud               % 波特率
        BufferSize    % 缓冲区大小。六通道，格式'float32'  24*16
        SCom
        
        % ----------- 神经网络部分 ----------- %
        Coef;                  % 权值
        
        % ----------- 扫描定位部分 ----------- %
        Fs;                    % 采样速率
        ScanRadius;                % 扫描半径
        ScanVelocity;              % 扫描速度
        CurrentAngle;                 % 当前角度
        ScanDirection;                    % 扫描方向
        DeltaY;                    % Y 轴增量
    end

    
    properties (Access = public)
        % ------------ 学习部分 ----------- %
        FileSavePath = '';           % 特征值文件位置
        FileSettings = '';           % 神经网络参数文件位置
        FileCoef = '';               % 权值文件

    end
    
    
    methods (Access = public)
        
        function setCom(app,ComCode,Baud,BufferSize)
            app.ComCode = ComCode;
            app.ComBaud = Baud;
            app.BufferSize = BufferSize;
        end
        function setSettingFile(app,FileName)
            app.FileSettings = FileName;
        end
        
        function setTableView(app,TableData)
            % Add data to the Table UI Component
            app.UITable.Data = TableData;
        end
        
        function setCoefFile(app,Coef)
            app.Coef = Coef;
        end
    end
    
    methods (Access = private)
        
        function openCom(app)
            app.SCom = serial(app.ComCode);
            set(app.SCom, 'BaudRate', app.ComBaud ,...
                'Parity', 'NONE', 'DataBits', 8, 'StopBits', 1);
            %% 尝试打开串口
            try
                if(strcmp(app.SCom.Status,'open'))
                    flose(app.SCom)
                end
                fopen(app.SCom);  %打开串口
            catch   % 若串口打开失败，提示“串口打开失败！”
                msgbox('串口打开失败！');
                return;
            end
            
        end
        
        function closeCom(app)
             %% 尝试关闭串口
            try
                if(strcmp(app.SCom.Status,'open'))
                    flose(app.SCom)
                    delete(app.SCom)
                    clear app.SCom
                end
            catch   % 若串口关闭失败，提示“串口关闭失败！”
                msgbox('串口关闭失败！');
                return;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'center');
%             fh = app.UIFigure;
%             fh.ToolBar = 'figure';
%             tbh = uitoolbar(fh);

        end

        % Menu selected function: MenuExit
        function MenuExitSelected(app, event)
%             closeCom(app);      % 调试时不要关闭串口。
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             closeCom(app);      % 调试时不要关闭串口。
            delete(app);
        end

        % Button pushed function: ButtonCharacteristicValue
        function ButtonCharacteristicValuePushed(app, event)
            app.FileSavePath = characteristicValue;
        end

        % Button pushed function: ButtonBPSettings
        function ButtonBPSettingsPushed(app, event)
            TrainSettings(app);
        end

        % Button pushed function: ButtonBPTrain
        function ButtonBPTrainPushed(app, event)
%             ax = app.UIAxes;
%             tb = axtoolbar(ax,{'zoomin','zoomout','datacursor','restoreview'}); 
            BPTrainMain(app);

        end

        % Button pushed function: ButtonBPCoefSave
        function ButtonBPCoefSavePushed(app, event)
            [filename,pathname] = uiputfile( {'*.mat';'*.*'}, '保存神经网络权值'); 
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            Coef = app.Coef;
            save([pathname,filename],'Coef');
            msgbox('神经网络权值保存成功！');            
        end

        % Button pushed function: ButtonCoefLoad
        function ButtonCoefLoadPushed(app, event)
            [filename,pathname] = uigetfile( {'*.mat';'*.*'}, '载入神经网络权值'); 
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            app.FileCoef = [pathname,filename];
%             load([pathname,filename],'Coef');

        end

        % Button pushed function: ButtonSingleTargetRecognize
        function ButtonSingleTargetRecognizePushed(app, event)
            if(isempty(app.FileCoef))
                msgbox('请载入权值文件');
                ButtonCoefLoadPushed(app, event);
            end
           
            % % 选择数据文件路径
            FolderName = uigetdir('..\OriginDat','请选择数据文件路径。');   
            % FolderName = 'E:\matlab\OriginDat\20190305';    % 调试时用这个默认路径。
            A = dir(fullfile(FolderName,'*.dat'));
            % %列表选择对话框
            AllFileNames = cell(size(A));
            for i=1:size(A,1)
                AllFileNames{i,1} = A(i,1).name;
            end
            [Sel,Ok]=listdlg('liststring',AllFileNames,...
                'listsize',[360 200],'OkString','确定','CancelString','取消',...
                'promptstring','请选择数据文件','name','请选择数据文件（多选）',...
                'selectionmode','single');
            if(Ok == 0)
                msgbox('您取消了选择文件。');
                return;
            end
            FileName = A(Sel,1);
            FullName = A(Sel,1);
            FileNumSel = size(FileName,1);
            for k=1:FileNumSel
                FullName(k,1).name = strcat(FolderName,'\', FileName(k,1).name);
            end
  
            targetRecognize(app,FullName,FileName);   % 进入识别函数。
        end

        % Button pushed function: ButtonMultiTargetRecognize
        function ButtonMultiTargetRecognizePushed(app, event)
            if(isempty(app.FileCoef))
                msgbox('请载入权值文件');
                ButtonCoefLoadPushed(app, event);
            end
            % % 选择数据文件路径
            FolderName = uigetdir('..\OriginDat','请选择数据文件路径。');   
            % FolderName = 'E:\matlab\OriginDat\20190305';    % 调试时用这个默认路径。
            A = dir(fullfile(FolderName,'*.dat'));
            % %列表选择对话框
            AllFileNames = cell(size(A));
            for i=1:size(A,1)
                AllFileNames{i,1} = A(i,1).name;
            end
            [Sel,Ok]=listdlg('liststring',AllFileNames,...
                'listsize',[360 500],'OkString','确定','CancelString','取消',...
                'promptstring','请选择数据文件','name','请选择数据文件（多选）',...
                'selectionmode','multiple');
            if(Ok == 0)
                msgbox('您取消了选择文件。');
                return;
            end
            FileName = A(Sel,1);
            FullName = A(Sel,1);
            FileNumSel = size(FileName,1);
            for k=1:FileNumSel
                FullName(k,1).name = strcat(FolderName,'\', FileName(k,1).name);
            end
            
            targetRecognize(app,FullName,FileName);   % 进入识别函数。
        end

        % Button pushed function: ButtonStartScan
        function ButtonStartScanPushed(app, event)
            app.Fs = app.EditFs.Value;                    % 采样速率
            app.ScanRadius = app.EditScanRadius.Value;                % 扫描半径
            app.ScanVelocity = app.EditScanVelocity.Value;              % 扫描速度
            app.CurrentAngle = app.EditCurrentAngle.Value;                 % 当前角度
            app.ScanDirection = app.Switch.Value;                    % 扫描方向
            app.DeltaY = app.EditDeltaY.Value;                    % Y 轴增量

%             openCom(app);
            app.ButtonStartScan.Text = '停止扫描';
            app.ButtonStartScan.BackgroundColor = 'g';
%             sendOrder();            % 发送指令，启动扫描。
            while(strcmp(app.ButtonStartScan.Text,'停止扫描'))
                if(app.CurrentAngle>=pi/4&&app.CurrentAngle<=3*pi/4)
                    dataFromComtoFile();    % 自动命名文件。
                    updateParameters();     % 更新数据
                    plotCurve();            % 画实时曲线
                else
                    break;
                end
            end
            
            app.ButtonStartScan.Text = '开始扫描';
            app.ButtonStartScan.BackgroundColor = [.96 .96 .96];
            saveDatatoFile();
            closeCom(app);
        end

        % Menu selected function: MenuComSettings
        function MenuComSettingsSelected(app, event)
            ComSettings(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '目标定位与识别系统';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MenuFile = uimenu(app.UIFigure);
            app.MenuFile.Text = '文件';
            app.MenuExit = uimenu(app.MenuFile);
            app.MenuExit.MenuSelectedFcn = createCallbackFcn(app, @MenuExitSelected, true);
            app.MenuExit.Text = '退出';
            app.MenuSettings = uimenu(app.UIFigure);
            app.MenuSettings.Text = '设置';
            app.MenuComSettings = uimenu(app.MenuSettings);
            app.MenuComSettings.MenuSelectedFcn = createCallbackFcn(app, @MenuComSettingsSelected, true);
            app.MenuComSettings.Text = '串口测试';
            app.MenuHelp = uimenu(app.UIFigure);
            app.MenuHelp.Text = '帮助';
            app.MenuVersion = uimenu(app.MenuHelp);
            app.MenuVersion.Text = '版本信息';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '样本学习';
            app.ButtonBPSettings = uibutton(app.Panel_3, 'push');
            app.ButtonBPSettings.ButtonPushedFcn = createCallbackFcn(app, @ButtonBPSettingsPushed, true);
            app.ButtonBPSettings.Text = '参数设置';
            app.ButtonBPTrain = uibutton(app.Panel_3, 'push');
            app.ButtonBPTrain.ButtonPushedFcn = createCallbackFcn(app, @ButtonBPTrainPushed, true);
            app.ButtonBPTrain.Text = '开始学习';
            app.ButtonBPCoefSave = uibutton(app.Panel_3, 'push');
            app.ButtonBPCoefSave.ButtonPushedFcn = createCallbackFcn(app, @ButtonBPCoefSavePushed, true);
            app.ButtonBPCoefSave.Text = '保存权值';
            app.ButtonCharacteristicValue = uibutton(app.Panel_3, 'push');
            app.ButtonCharacteristicValue.ButtonPushedFcn = createCallbackFcn(app, @ButtonCharacteristicValuePushed, true);
            app.ButtonCharacteristicValue.Text = '提取特征值';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '目标识别';
            app.ButtonMultiTargetRecognize = uibutton(app.Panel_4, 'push');
            app.ButtonMultiTargetRecognize.ButtonPushedFcn = createCallbackFcn(app, @ButtonMultiTargetRecognizePushed, true);
            app.ButtonMultiTargetRecognize.Text = '批量识别';
            app.ButtonSingleTargetRecognize = uibutton(app.Panel_4, 'push');
            app.ButtonSingleTargetRecognize.ButtonPushedFcn = createCallbackFcn(app, @ButtonSingleTargetRecognizePushed, true);
            app.ButtonSingleTargetRecognize.Text = '单个识别';
            app.ButtonRealTimeTargetRecognize = uibutton(app.Panel_4, 'push');
            app.ButtonRealTimeTargetRecognize.Enable = 'off';
            app.ButtonRealTimeTargetRecognize.Text = '保留';
            app.ButtonCoefLoad = uibutton(app.Panel_4, 'push');
            app.ButtonCoefLoad.ButtonPushedFcn = createCallbackFcn(app, @ButtonCoefLoadPushed, true);
            app.ButtonCoefLoad.Text = '载入权值';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'序号'; '文件名'; '识别结果'};
            app.UITable.RowName = {};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '目标搜索与定位';
            app.ButtonStartScan = uibutton(app.Panel_2, 'push');
            app.ButtonStartScan.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartScanPushed, true);
            app.ButtonStartScan.Text = '开始扫描';
            app.ButtonContour = uibutton(app.Panel_2, 'push');
            app.ButtonContour.Text = '区域成像';
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.Items = {'顺时针', '逆时针'};
            app.Switch.Value = '顺时针';
            app.YEditFieldLabel = uilabel(app.Panel_2);
            app.YEditFieldLabel.Text = 'Y轴增量';
            app.EditDeltaY = uieditfield(app.Panel_2, 'numeric');
            app.EditDeltaY.Limits = [-10 10];
            app.EditDeltaY.Value = 2;
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '米';
            app.EditFieldLabel = uilabel(app.Panel_2);
            app.EditFieldLabel.Text = '扫描半径';
            app.EditScanRadius = uieditfield(app.Panel_2, 'numeric');
            app.EditScanRadius.Limits = [0 2];
            app.EditScanRadius.Value = 1.5;
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '扫描方向';
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '米/秒';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '扫描速度';
            app.EditScanVelocity = uieditfield(app.Panel_2, 'numeric');
            app.EditScanVelocity.Limits = [0 2];
            app.EditScanVelocity.Value = 0.4;
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '度';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '当前角度';
            app.EditCurrentAngle = uieditfield(app.Panel_2, 'numeric');
            app.EditCurrentAngle.Limits = [-180 180];
            app.EditCurrentAngle.Value = 135;
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '次/秒';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '采样速率';
            app.EditFs = uieditfield(app.Panel_2, 'numeric');
            app.EditFs.Limits = [0 200];
            app.EditFs.Value = 80;
            app.Label_9 = uilabel(app.Panel_2);
            app.Label_9.Text = '厘米';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '累积增量';
            app.EditAccumulateDeltaY = uieditfield(app.Panel_2, 'numeric');
            app.EditAccumulateDeltaY.Editable = 'off';
            app.EditAccumulateDeltaY.Enable = 'off';
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = '厘米';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end